classdef PIDParentAPPmlapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HOWITWORKSChooseyourdesiredinputsignalforwhichyourtuningofPIDControllerwillbringyourV_outtothatreferenceForsimplicityallinputsassum1vMaximumasidefromUserDefinedReferencespecificallyforUnitStepLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Panel_3                         matlab.ui.container.Panel
        UnitInputSquareReferenceButton  matlab.ui.control.Button
        SquareInputUnitreferenceButton  matlab.ui.control.Button
        SquareInputSquareReferenceButton  matlab.ui.control.Button
        StepUnitInputRampReferenceButton  matlab.ui.control.Button
        RampInputRampReferenceButton    matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        ReferenceEditField              matlab.ui.control.NumericEditField
        ReferenceEditFieldLabel         matlab.ui.control.Label
        UserDefinedReferenceButton      matlab.ui.control.Button
        UnitStepButton                  matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        SquareInputaSinwtReferenceButton  matlab.ui.control.Button
        UnitInputaSinwtreferenceButton  matlab.ui.control.Button
        WarningConfigurevariablesaccordinglyLabel  matlab.ui.control.Label
        USERTUNEABLEPIDControllerLabel  matlab.ui.control.Label
        DisclaimerMODELONLYACCOUNTSFORSTEADYSTATELabel  matlab.ui.control.Label
        PIDControllerConsideraRLCcircuitwheregeneralpolynomialtransferfunctionHsisundertheinfluenceofaPIDControllerforcingyourHstoaspecifiedreferencepointThePIDcontrollerwontexactlychangeyourfixedvaluesofRLCbutrathercontroltheV_intoachieveadesiredV_outV_outHsxV_inDeterminedesiredV_outhenceHsLetPIDControllertakecontrolApplicationsforRLCcircuit1VoltageRegulator2AudioAmplifier3MotorSpeedControl4FilterDesigninCommunications5BiomedicalDevicesLabel  matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UnitStepButton
        function UnitStepButtonPushed(app, event)
            PIDtest();
        end

        % Button pushed function: UserDefinedReferenceButton
        function UserDefinedReferenceButtonPushed(app, event)
            pid = PIDtest_UserDefined();
            pid.Reference = app.ReferenceEditField.Value;

        end

        % Button pushed function: UnitInputaSinwtreferenceButton
        function UnitInputaSinwtreferenceButtonPushed(app, event)
            pid = PIDtest_SINX();
            pid.Amplitude = app.AmplitudeEditField.Value;
            pid.Frequency = app.FrequencyEditField.Value;
        end

        % Button pushed function: RampInputRampReferenceButton
        function RampInputRampReferenceButtonPushed(app, event)
            PIDtestRampToRamp();
        end

        % Button pushed function: StepUnitInputRampReferenceButton
        function StepUnitInputRampReferenceButtonPushed(app, event)
            PIDtestUnitToRamp();
        end

        % Button pushed function: SquareInputaSinwtReferenceButton
        function SquareInputaSinwtReferenceButtonPushed(app, event)
            pid = PIDtestSquareToSin();
            pid.Amplitude = app.AmplitudeEditField.Value;
            pid.Frequency = app.FrequencyEditField.Value;
        end

        % Button pushed function: SquareInputSquareReferenceButton
        function SquareInputSquareReferenceButtonPushed(app, event)
            PIDtestSquareToSquare();
        end

        % Button pushed function: SquareInputUnitreferenceButton
        function SquareInputUnitreferenceButtonPushed(app, event)
            PIDtestSquareToUnit();
        end

        % Button pushed function: UnitInputSquareReferenceButton
        function UnitInputSquareReferenceButtonPushed(app, event)
            PIDtestUnitToSquare();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PIDControllerConsideraRLCcircuitwheregeneralpolynomialtransferfunctionHsisundertheinfluenceofaPIDControllerforcingyourHstoaspecifiedreferencepointThePIDcontrollerwontexactlychangeyourfixedvaluesofRLCbutrathercontroltheV_intoachieveadesiredV_outV_outHsxV_inDeterminedesiredV_outhenceHsLetPIDControllertakecontrolApplicationsforRLCcircuit1VoltageRegulator2AudioAmplifier3MotorSpeedControl4FilterDesigninCommunications5BiomedicalDevicesLabel = uilabel(app.UIFigure);
            app.PIDControllerConsideraRLCcircuitwheregeneralpolynomialtransferfunctionHsisundertheinfluenceofaPIDControllerforcingyourHstoaspecifiedreferencepointThePIDcontrollerwontexactlychangeyourfixedvaluesofRLCbutrathercontroltheV_intoachieveadesiredV_outV_outHsxV_inDeterminedesiredV_outhenceHsLetPIDControllertakecontrolApplicationsforRLCcircuit1VoltageRegulator2AudioAmplifier3MotorSpeedControl4FilterDesigninCommunications5BiomedicalDevicesLabel.Text = {'PID Controller:'; 'Consider a RLC circuit where general polynomial transfer function H(s)'; 'is under the influence of a PID Controller forcing your H(s) to a specified '; 'reference point. The PID controller wont exactly change your fixed values '; 'of R, L, & C but rather control the V_in to achieve a desired V_out. '; ''; 'V_out = H(s) x V_in   [Determine desired V_out, hence H(s). Let PID Controller take control!]'; ''; 'Applications for RLC circuit:'; '1. Voltage Regulator '; '2. Audio Amplifier'; '3. Motor Speed Control'; '4. Filter Design in Communications'; '5. Biomedical Devices'};
            app.DisclaimerMODELONLYACCOUNTSFORSTEADYSTATELabel = uilabel(app.UIFigure);
            app.DisclaimerMODELONLYACCOUNTSFORSTEADYSTATELabel.Text = 'Disclaimer: MODEL ONLY ACCOUNTS FOR STEADY STATE';
            app.USERTUNEABLEPIDControllerLabel = uilabel(app.UIFigure);
            app.USERTUNEABLEPIDControllerLabel.Text = 'USER TUNEABLE PID Controller';
            app.Panel = uipanel(app.UIFigure);
            app.WarningConfigurevariablesaccordinglyLabel = uilabel(app.Panel);
            app.WarningConfigurevariablesaccordinglyLabel.Text = 'Warning: Configure variables accordingly';
            app.UnitInputaSinwtreferenceButton = uibutton(app.Panel, 'push');
            app.UnitInputaSinwtreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @UnitInputaSinwtreferenceButtonPushed, true);
            app.UnitInputaSinwtreferenceButton.Text = 'Unit Input --> aSin(wt) reference';
            app.SquareInputaSinwtReferenceButton = uibutton(app.Panel, 'push');
            app.SquareInputaSinwtReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @SquareInputaSinwtReferenceButtonPushed, true);
            app.SquareInputaSinwtReferenceButton.Text = 'Square Input --> aSin(wt) Reference';
            app.AmplitudeEditFieldLabel = uilabel(app.Panel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.Panel, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.Panel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.UIFigure);
            app.UnitStepButton = uibutton(app.Panel_2, 'push');
            app.UnitStepButton.ButtonPushedFcn = createCallbackFcn(app, @UnitStepButtonPushed, true);
            app.UnitStepButton.Text = 'Unit Step';
            app.UserDefinedReferenceButton = uibutton(app.Panel_2, 'push');
            app.UserDefinedReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @UserDefinedReferenceButtonPushed, true);
            app.UserDefinedReferenceButton.Text = 'UserDefined Reference';
            app.ReferenceEditFieldLabel = uilabel(app.Panel_2);
            app.ReferenceEditFieldLabel.Text = 'Reference';
            app.ReferenceEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.UIFigure);
            app.RampInputRampReferenceButton = uibutton(app.Panel_3, 'push');
            app.RampInputRampReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @RampInputRampReferenceButtonPushed, true);
            app.RampInputRampReferenceButton.Text = 'Ramp Input --> Ramp Reference';
            app.StepUnitInputRampReferenceButton = uibutton(app.Panel_3, 'push');
            app.StepUnitInputRampReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @StepUnitInputRampReferenceButtonPushed, true);
            app.StepUnitInputRampReferenceButton.Text = 'Step Unit Input --> Ramp Reference';
            app.SquareInputSquareReferenceButton = uibutton(app.Panel_3, 'push');
            app.SquareInputSquareReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @SquareInputSquareReferenceButtonPushed, true);
            app.SquareInputSquareReferenceButton.Text = 'Square Input --> Square Reference';
            app.SquareInputUnitreferenceButton = uibutton(app.Panel_3, 'push');
            app.SquareInputUnitreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @SquareInputUnitreferenceButtonPushed, true);
            app.SquareInputUnitreferenceButton.Text = 'Square Input --> Unit reference';
            app.UnitInputSquareReferenceButton = uibutton(app.Panel_3, 'push');
            app.UnitInputSquareReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @UnitInputSquareReferenceButtonPushed, true);
            app.UnitInputSquareReferenceButton.Text = 'Unit Input --> Square Reference ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'App', 'RLC.jpg');
            app.HOWITWORKSChooseyourdesiredinputsignalforwhichyourtuningofPIDControllerwillbringyourV_outtothatreferenceForsimplicityallinputsassum1vMaximumasidefromUserDefinedReferencespecificallyforUnitStepLabel = uilabel(app.UIFigure);
            app.HOWITWORKSChooseyourdesiredinputsignalforwhichyourtuningofPIDControllerwillbringyourV_outtothatreferenceForsimplicityallinputsassum1vMaximumasidefromUserDefinedReferencespecificallyforUnitStepLabel.Text = {'HOW IT WORKS'; 'Choose your desired input signal for which your tuning of PID Controller'; 'will bring your V_out to that reference. '; ''; 'For simplicity all inputs assum 1v Maximum aside from "UserDefined Reference" '; '(specifically for Unit Step)'; ''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIDParentAPPmlapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end