classdef PIDtestSquareToSin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TransferFunctionof2ndorderdifferentialequationLabel  matlab.ui.control.Label
        ReferenceSetpointSineWaveLabel  matlab.ui.control.Label
        INPUTSquareLabel                matlab.ui.control.Label
        PIDControllerLabel              matlab.ui.control.Label
        MENUButton                      matlab.ui.control.Button
        DisclaimerNeedControlSystemAddonLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        PIDControllerVariablesTuningLabel  matlab.ui.control.Label
        b0EditField                     matlab.ui.control.NumericEditField
        b0EditFieldLabel                matlab.ui.control.Label
        b1EditField                     matlab.ui.control.NumericEditField
        b1EditFieldLabel                matlab.ui.control.Label
        b2EditField                     matlab.ui.control.NumericEditField
        b2EditFieldLabel                matlab.ui.control.Label
        a0EditField                     matlab.ui.control.NumericEditField
        a0EditFieldLabel                matlab.ui.control.Label
        a1EditField                     matlab.ui.control.NumericEditField
        a1EditFieldLabel                matlab.ui.control.Label
        a2EditField                     matlab.ui.control.NumericEditField
        a2EditFieldLabel                matlab.ui.control.Label
        KiEditField                     matlab.ui.control.NumericEditField
        KiEditFieldLabel                matlab.ui.control.Label
        KdEditField                     matlab.ui.control.NumericEditField
        KdEditFieldLabel                matlab.ui.control.Label
        KpEditField                     matlab.ui.control.NumericEditField
        KpEditFieldLabel                matlab.ui.control.Label
        ComputeClosedLoopSystemButton   matlab.ui.control.Button
        PlantSignalsTransferFunctionVariablesTuningLabel  matlab.ui.control.Label
        BodeAxes                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Amplitude double = 1
        Frequency double = 1
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ComputeClosedLoopSystemButton, KdEditField, 
        % ...and 8 other components
        function ComputeClosedLoopSystemButtonPushed(app, event)
          if app.a2EditField.Value == 0 && app.a1EditField.Value == 0 && app.a0EditField.Value == 0
        return
    end
            
            % ===== PID gains =====
Kp = app.KpEditField.Value;
Ki = app.KiEditField.Value;
Kd = app.KdEditField.Value;

C = pid(Kp, Ki, Kd);

% ===== Plant coefficients =====
b2 = app.b2EditField.Value;
b1 = app.b1EditField.Value;
b0 = app.b0EditField.Value;

a2 = app.a2EditField.Value;
a1 = app.a1EditField.Value;
a0 = app.a0EditField.Value;


% ===== Transfer function =====
G = tf([b2 b1 b0], [a2 a1 a0]);

% ===== Closed-loop system =====
T = feedback(C * G, 1);

% ===== Time vector =====
t = 0:0.0001:20;

% ===== Compute responses WITHOUT opening figures =====

% Step unit
u = square(t);
[y_plant, t1] = lsim(G, u, t);
q = app.Amplitude * sin(app.Frequency * t);
[y_closed, t2] = lsim(T, q, t);

% If i want different reference

%  u = ... ;   % define input signal
%  [y_closed, t_out] = lsim(T, u, t);

%  Example
%   u = sin(t);
%   [y_closed, t_out] = lsim(T, u, t);

% ===== Main response plot =====
cla(app.UIAxes);

plot(app.UIAxes, t1, y_plant, '--', 'LineWidth', 1.5);
hold(app.UIAxes, 'on');

plot(app.UIAxes, t2, y_closed, 'LineWidth', 1.5);

hold(app.UIAxes, 'off');

xlim(app.UIAxes, 'auto');
ylim(app.UIAxes, 'auto');

grid(app.UIAxes, 'on');

title(app.UIAxes, 'Output: Plant vs PID Controlled System');
xlabel(app.UIAxes, 'Time (s)');
ylabel(app.UIAxes, 'Output');

legend(app.UIAxes, ...
    'Plant (Open-loop)', ...
    'With PID (Closed-loop)');

% =====================================================
% ===== Bode Plot (Magnitude + Phase of Plant) =====
% =====================================================

% Create bode plot INSIDE app axes if available
% Recommended: add another UIAxes named app.BodeAxes

cla(app.BodeAxes);

bode(app.BodeAxes, G);

grid(app.BodeAxes, 'on');

title(app.BodeAxes, 'Input Bode Plot of Original Plant');
        end

        % Button pushed function: MENUButton
        function MENUButtonPushed(app, event)
            PIDParentAPPmlapp();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Output: Plant vs PID Controlled System')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BodeAxes = uiaxes(app.UIFigure);
            title(app.BodeAxes, 'Bode Diagram')
            xlabel(app.BodeAxes, 'X')
            ylabel(app.BodeAxes, 'Y')
            zlabel(app.BodeAxes, 'Z')
            app.PlantSignalsTransferFunctionVariablesTuningLabel = uilabel(app.UIFigure);
            app.PlantSignalsTransferFunctionVariablesTuningLabel.Text = {'Plant (Signals Transfer Function)'; 'Variables - Tuning'};
            app.ComputeClosedLoopSystemButton = uibutton(app.UIFigure, 'push');
            app.ComputeClosedLoopSystemButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeClosedLoopSystemButtonPushed, true);
            app.ComputeClosedLoopSystemButton.Text = 'Compute Closed-Loop System';
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.UIFigure, 'numeric');
            app.KpEditField.ValueChangedFcn = createCallbackFcn(app, @ComputeClosedLoopSystemButtonPushed, true);
            app.KdEditFieldLabel = uilabel(app.UIFigure);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.UIFigure, 'numeric');
            app.KdEditField.ValueChangedFcn = createCallbackFcn(app, @ComputeClosedLoopSystemButtonPushed, true);
            app.KiEditFieldLabel = uilabel(app.UIFigure);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.UIFigure, 'numeric');
            app.KiEditField.ValueChangedFcn = createCallbackFcn(app, @ComputeClosedLoopSystemButtonPushed, true);
            app.a2EditFieldLabel = uilabel(app.UIFigure);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.UIFigure, 'numeric');
            app.a2EditField.ValueChangedFcn = createCallbackFcn(app, @ComputeClosedLoopSystemButtonPushed, true);
            app.a1EditFieldLabel = uilabel(app.UIFigure);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.UIFigure, 'numeric');
            app.a1EditField.ValueChangedFcn = createCallbackFcn(app, @ComputeClosedLoopSystemButtonPushed, true);
            app.a0EditFieldLabel = uilabel(app.UIFigure);
            app.a0EditFieldLabel.Text = 'a0';
            app.a0EditField = uieditfield(app.UIFigure, 'numeric');
            app.a0EditField.ValueChangedFcn = createCallbackFcn(app, @ComputeClosedLoopSystemButtonPushed, true);
            app.b2EditFieldLabel = uilabel(app.UIFigure);
            app.b2EditFieldLabel.Text = 'b2';
            app.b2EditField = uieditfield(app.UIFigure, 'numeric');
            app.b2EditField.ValueChangedFcn = createCallbackFcn(app, @ComputeClosedLoopSystemButtonPushed, true);
            app.b1EditFieldLabel = uilabel(app.UIFigure);
            app.b1EditFieldLabel.Text = 'b1';
            app.b1EditField = uieditfield(app.UIFigure, 'numeric');
            app.b1EditField.ValueChangedFcn = createCallbackFcn(app, @ComputeClosedLoopSystemButtonPushed, true);
            app.b0EditFieldLabel = uilabel(app.UIFigure);
            app.b0EditFieldLabel.Text = 'b0';
            app.b0EditField = uieditfield(app.UIFigure, 'numeric');
            app.b0EditField.ValueChangedFcn = createCallbackFcn(app, @ComputeClosedLoopSystemButtonPushed, true);
            app.PIDControllerVariablesTuningLabel = uilabel(app.UIFigure);
            app.PIDControllerVariablesTuningLabel.Text = 'PID Controller Variables - Tuning';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'App', 'Copilot_20260507_144611.png');
            app.DisclaimerNeedControlSystemAddonLabel = uilabel(app.UIFigure);
            app.DisclaimerNeedControlSystemAddonLabel.Text = 'Disclaimer: Need Control System Add-on';
            app.MENUButton = uibutton(app.UIFigure, 'push');
            app.MENUButton.ButtonPushedFcn = createCallbackFcn(app, @MENUButtonPushed, true);
            app.MENUButton.Text = 'MENU';
            app.PIDControllerLabel = uilabel(app.UIFigure);
            app.PIDControllerLabel.Text = 'PID Controller';
            app.INPUTSquareLabel = uilabel(app.UIFigure);
            app.INPUTSquareLabel.Text = 'INPUT: Square';
            app.ReferenceSetpointSineWaveLabel = uilabel(app.UIFigure);
            app.ReferenceSetpointSineWaveLabel.Text = 'Reference/Setpoint: Sine Wave';
            app.TransferFunctionof2ndorderdifferentialequationLabel = uilabel(app.UIFigure);
            app.TransferFunctionof2ndorderdifferentialequationLabel.Text = 'Transfer Function of 2nd order differential equation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIDtestSquareToSin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end