classdef TestingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LowPassFilterDropDown       matlab.ui.control.DropDown
        LowPassFilterDropDownLabel  matlab.ui.control.Label
        LocalOsc_FreqSlider         matlab.ui.control.Slider
        LocalOsc_FreqSliderLabel    matlab.ui.control.Label
        FrequencySlider_2           matlab.ui.control.Slider
        FrequencySlider_2Label      matlab.ui.control.Label
        PhaseSlider_2               matlab.ui.control.Slider
        PhaseSlider_2Label          matlab.ui.control.Label
        AmplitudeSlider             matlab.ui.control.Slider
        AmplitudeSliderLabel        matlab.ui.control.Label
        CreateSinusoidButton        matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateSinusoidButton
        function CreateSinusoidButtonPushed(app, event)
            
            % -----------------------------
            % Sampling (same style as script)
            % -----------------------------
            Fs = 5000;
            dt = 1/Fs;
            t = 0:dt:1-dt;
            N = length(t);

            % Frequency axis (manual FT style)
            f = linspace(0, Fs/2, 2000);

            % -----------------------------
            % Inputs
            % -----------------------------
            A = app.AmplitudeSlider.Value;
            Fsig = app.FrequencySlider_2.Value;
            Phase = app.PhaseSlider_2.Value;
            Flo = app.LocalOsc_FreqSlider.Value;

            % -----------------------------
            % Baseband signal
            % -----------------------------
            g = A * cos(2*pi*Fsig*t + Phase);

            % -----------------------------
            % Modulation
            % -----------------------------
            carrier_tx = 2*cos(2*pi*Flo*t);
            g_am = 0.5 .* g .* carrier_tx;

            % -----------------------------
            % Demodulation (mixing)
            % -----------------------------
            carrier_rx = 2*cos(2*pi*Flo*t);
            g_mix = 0.5 .* g_am .* carrier_rx;

            % -----------------------------
            % LPF
            % -----------------------------
            filterType = app.LowPassFilterDropDown.Value;

            switch filterType
                case 'None'
                    g_rec = g_mix;

                case 'Elliptic LPF'
                    order = 6;
                    Rp = 0.1;
                    Rs = 50;

                    cutoff = Fsig * 2;

                    [b,a] = ellip(order, Rp, Rs, cutoff/(Fs/2));

                    H0 = sum(b)/sum(a);
                    g_rec = (2/H0) * filter(b, a, g_mix);
            end

            % DC removal
            g_rec = g_rec - mean(g_rec);

            % =====================================================
            % MANUAL FOURIER TRANSFORM (LIKE YOUR SCRIPT)
            % =====================================================

            Xg = zeros(size(f));
            Xg_rec = zeros(size(f));

            for k = 1:length(f)
                fk = f(k);

                Xg(k) = sum(g .* exp(-1j * 2*pi*fk * t)) * dt;
                Xg_rec(k) = sum(g_rec .* exp(-1j * 2*pi*fk * t)) * dt;
            end

            % Convert to dB
            y_g = 20*log10(abs(Xg) + 1e-12);
            y_rec = 20*log10(abs(Xg_rec) + 1e-12);

            % -----------------------------
            % Plot spectrum (original)
            % -----------------------------
            plot(app.UIAxes, f, y_g);
            grid(app.UIAxes, 'on');
            title(app.UIAxes, 'Original Spectrum');
            xlabel(app.UIAxes, 'Frequency (Hz)');
            ylabel(app.UIAxes, 'Magnitude (dB)');

            % -----------------------------
            % Plot comparison spectrum
            % -----------------------------
            plot(app.UIAxes_2, f, y_g, 'b');
            hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, f, y_rec, 'r');
            grid(app.UIAxes_2, 'on');

            legend(app.UIAxes_2, 'Original', 'Recovered');
            title(app.UIAxes_2, 'Spectrum Comparison');
            xlabel(app.UIAxes_2, 'Frequency (Hz)');
            ylabel(app.UIAxes_2, 'Magnitude (dB)');
            hold(app.UIAxes_2, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Modulating Signal')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'X')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Output Signal')
            xlabel(app.UIAxes_2, 't')
            ylabel(app.UIAxes_2, 'X')
            zlabel(app.UIAxes_2, 'Z')
            app.CreateSinusoidButton = uibutton(app.UIFigure, 'push');
            app.CreateSinusoidButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSinusoidButtonPushed, true);
            app.CreateSinusoidButton.Text = 'Create Sinusoid';
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.PhaseSlider_2Label = uilabel(app.UIFigure);
            app.PhaseSlider_2Label.Text = 'Phase';
            app.PhaseSlider_2 = uislider(app.UIFigure);
            app.FrequencySlider_2Label = uilabel(app.UIFigure);
            app.FrequencySlider_2Label.Text = 'Frequency';
            app.FrequencySlider_2 = uislider(app.UIFigure);
            app.FrequencySlider_2.Limits = [0 1000];
            app.LocalOsc_FreqSliderLabel = uilabel(app.UIFigure);
            app.LocalOsc_FreqSliderLabel.Text = 'LocalOsc_Freq';
            app.LocalOsc_FreqSlider = uislider(app.UIFigure);
            app.LowPassFilterDropDownLabel = uilabel(app.UIFigure);
            app.LowPassFilterDropDownLabel.Text = 'Low Pass Filter';
            app.LowPassFilterDropDown = uidropdown(app.UIFigure);
            app.LowPassFilterDropDown.Items = {'None', 'Elliptic LPF'};
            app.LowPassFilterDropDown.Value = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end